classdef my_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        ControllerPanel            matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        Panel_3                    matlab.ui.container.Panel
        KdEditField                matlab.ui.control.EditField
        KdEditFieldLabel           matlab.ui.control.Label
        KiEditField                matlab.ui.control.EditField
        KiEditFieldLabel           matlab.ui.control.Label
        KpEditField                matlab.ui.control.EditField
        KpEditFieldLabel           matlab.ui.control.Label
        FeedbackPositionPanel      matlab.ui.container.Panel
        VEditField                 matlab.ui.control.EditField
        VEditFieldLabel            matlab.ui.control.Label
        degEditField_2             matlab.ui.control.EditField
        degEditField_2Label        matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        DesiredPositionPanel       matlab.ui.container.Panel
        SetButton                  matlab.ui.control.Button
        degEditField               matlab.ui.control.EditField
        degEditFieldLabel          matlab.ui.control.Label
        SerialPortConnectionPanel  matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        Panel_2                    matlab.ui.container.Panel
        Label                      matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        StatusLampLabel            matlab.ui.control.Label
        ConnectButton              matlab.ui.control.Button
        PhysicalModeCheckBox       matlab.ui.control.CheckBox
        Panel                      matlab.ui.container.Panel
        BaudrateDropDown           matlab.ui.control.DropDown
        BaudrateDropDownLabel      matlab.ui.control.Label
        NameListBox                matlab.ui.control.ListBox
        NameListBoxLabel           matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SerialConnection
    end
    
    properties (Access = public)
        index = 1;
        timeCounter = 0;
        timeCounterReal = 0;
        time = [];
        feedback = [];

        setpointLine    matlab.graphics.chart.primitive.Line
        errorLine1      matlab.graphics.chart.primitive.Line
        errorLine2      matlab.graphics.chart.primitive.Line
        settlingLine    matlab.graphics.chart.primitive.Line
    end
    
    methods (Access = public)
        function updateSerialPortList(app)
            serialPorts = serialportlist;
            app.NameListBox.Items = serialPorts;
        end
        
        function callbackSerial(app, ~, ~)
            simulationStatus = get_param('main', 'SimulationStatus');
            if strcmp(simulationStatus, 'running')
                app.SetButton.Enable = 'on';
                app.StartButton.Enable = 'on';

                byte = read(app.SerialConnection, 1, 'uint8');
            
                if byte == 0x02
                    voltage = read(app.SerialConnection, 1, 'single');
                    voltage = max(-12, min(12, voltage));
                    assignin('base', 'voltage', voltage);
                    app.VEditField.Value = string(voltage);
                    
                    app.time(app.index) = toc;
                    
                    f = evalin('base', 'feedback');
                    app.feedback(app.index) = f;
                    
                    write(app.SerialConnection, uint8(0x02), "uint8");
                    write(app.SerialConnection, f, "single");
                    
                    updatePlot(app);
                    
                    app.degEditField_2.Value = string(f);
                    app.index = app.index + 1;
    
                    flush(app.SerialConnection);
                end
            end
        end

        function callbackSerialReal(app, ~, ~)
            byte = read(app.SerialConnection, 1, 'uint8');
            
            if byte == 0x02
                f = read(app.SerialConnection, 1, 'single');
                app.degEditField_2.Value = string(f);
                app.feedback(app.index) = f;
                
                app.time(app.index) = toc;
                
                updatePlot(app);

                app.index = app.index + 1;

                flush(app.SerialConnection);
            end
        end

        function updatePlot(app)
            app.feedback(1) = 0;

            plot(app.UIAxes, app.time(1:app.index-1), app.feedback(1:app.index-1), 'b-', 'Marker', 'o', 'MarkerEdgeColor', 'b');
            xlabel(app.UIAxes, 'time (s)');
            ylabel(app.UIAxes, 'position (degrees)');
            title(app.UIAxes, 'Position vs. Time');
            grid(app.UIAxes, 'on');
        end

        function endPlot(app)
            setpoint = evalin('base', 'setpoint');
            
            % Hold on to retain all previous plots
            hold(app.UIAxes, 'on');
            
            try
                % Setpoint line (constant horizontal line)
                if isempty(app.setpointLine) || ~isvalid(app.setpointLine)
                    app.setpointLine = plot(app.UIAxes, [0, max(app.time)], [setpoint, setpoint], 'g--', 'LineWidth', 2);
                else
                    set(app.setpointLine, 'YData', [setpoint, setpoint]);
                end
            catch ME
                disp('An error occurred while updating the setpoint line:');
                disp(ME.message);
            end

            
            % Plot feedback vs. time
            plot(app.UIAxes, app.time(1:app.index-1), app.feedback(1:app.index-1), 'b-', 'Marker', 'o', 'MarkerEdgeColor', 'b');
            
            % Error lines (±5% of setpoint)
            errorLower = setpoint * (1 - 0.05);
            errorUpper = setpoint * (1 + 0.05);
            
            if isempty(app.errorLine1) || ~isvalid(app.errorLine1)
                app.errorLine1 = plot(app.UIAxes, [0, max(app.time)], [errorLower, errorLower], 'r--', 'LineWidth', 2);
            else
                set(app.errorLine1, 'YData', [errorLower, errorLower]);
            end
            
            if isempty(app.errorLine2) || ~isvalid(app.errorLine2)
                app.errorLine2 = plot(app.UIAxes, [0, max(app.time)], [errorUpper, errorUpper], 'r--', 'LineWidth', 2);
            else
                set(app.errorLine2, 'YData', [errorUpper, errorUpper]);
            end
            
            % Settling time calculation (tolerance of ±5%)
            tolerance = 0.05 * setpoint;
            settledIndex = find(abs(app.feedback - setpoint) < tolerance, 1, 'first');
            
            if ~isempty(settledIndex)
                settlingTime = app.time(settledIndex);
                
                if isempty(app.settlingLine) || ~isvalid(app.settlingLine)
                    app.settlingLine = plot(app.UIAxes, [settlingTime, settlingTime], ylim(app.UIAxes), 'k--', 'LineWidth', 2);
                else
                    set(app.settlingLine, 'XData', [settlingTime, settlingTime]);
                end
            end
            
            % Calculate percentage overshoot
            maxFeedback = max(app.feedback);
            if maxFeedback > setpoint
                overshoot = ((maxFeedback - setpoint) / setpoint) * 100;
            else
                overshoot = 0;
            end
            
            try
                xlim(app.UIAxes, [0, max(app.time)]);
                % ylim(app.UIAxes, [0, errorUpper + 10]);
            catch ME
                disp('Error adjusting axis limits:');
                disp(ME.message);
                xlim(app.UIAxes, [0, 10]);
            end
        
            xlabel(app.UIAxes, 'time (s)');
            ylabel(app.UIAxes, 'position (degrees)');
            title(app.UIAxes, 'Position vs. Time');
            
            % Enable grid
            grid(app.UIAxes, 'on');
            
            try
                message = sprintf('Percentage Overshoot: %.2f%%\nSettling Time: %.2f seconds\nFinal value: %.2f', overshoot, settlingTime, app.feedback(end));
            catch
                message = sprintf('Percentage Overshoot: %.2f%%\nSettling Time: Not Settled Yet\nFinal value: %.2f', overshoot, app.feedback(end));
            end
            
            msgbox(message, 'Result');
        end
    end
    
    methods (Access = public)
        function CloseRequest(app, ~)
            if ~isempty(app.SerialConnection) && isvalid(app.SerialConnection) && strcmp(app.SerialConnection.Status, 'open')
                fclose(app.SerialConnection);
                delete(app.SerialConnection);
                disp('Serial connection closed.');
            end
            
            app.delete;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateSerialPortList(app);
            app.timeCounterReal = timer('Period', 0.01, 'ExecutionMode', ...
                'fixedSpacing', 'TasksToExecute', Inf, ...
                'TimerFcn', @app.callbackSerialReal);
            app.timeCounter = timer('Period', 0.01, 'ExecutionMode', ...
                'fixedSpacing', 'TasksToExecute', Inf, ...
                'TimerFcn', @app.callbackSerial);

            K = {};

            if evalin('base', 'exist(''K'', ''var'')') ~= 1
                Kp = 1;
                Ki = 0;  
                Kd = 0;  
                evalin('base', sprintf('K = {%.2f, %.2f, %.2f};', Kp, Ki, Kd));
            end
            
            K = evalin('base', 'K');
            app.KpEditField.Value = string(K{1});
            app.KiEditField.Value = string(K{2});
            app.KdEditField.Value = string(K{3});

            if evalin('base', 'exist(''setpoint'', ''var'')') ~= 1
                setpoint_ = 100.0;
                evalin('base', sprintf('setpoint = ''%.2f'';', setpoint_));
                app.degEditField.Value = string(setpoint_);
            else
                app.degEditField.Value = string(evalin('base', 'setpoint'));
            end
            
            evalin('base', 'voltage = 0;');
            evalin('base', 'feedback = 0;');
            
            app.UIFigure.CloseRequestFcn = @(~,~) app.CloseRequest();
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if strcmp(app.ConnectButton.Text, 'Disconnect')
                if ~isempty(app.SerialConnection) && isvalid(app.SerialConnection)
                    delete(app.SerialConnection);
                    app.SerialConnection = [];
                end
                app.StatusLamp.Color = [1, 0, 0];
                app.ConnectButton.Text = 'Connect';
        
                app.NameListBox.Enable = 'on';
                app.BaudrateDropDown.Enable = 'on';
                app.PhysicalModeCheckBox.Enable = 'on';
                app.SetButton.Enable = 'off';
                app.StartButton.Enable = 'off';

                app.KpEditField.Enable = 'on';
                app.KiEditField.Enable = 'on';
                app.KdEditField.Enable = 'on';

                evalin('base', 'voltage = 0;');
                evalin('base', 'feedback = 0;');
        
                if strcmp(app.StartButton.Text, 'Stop')
                    app.StartButton.Text = 'Start';
                end
            else
                selectedPort = app.NameListBox.Value;
                
                if isempty(selectedPort) && ~isempty(app.NameListBox.Items)
                    selectedPort = app.NameListBox.Items{1};
                elseif isempty(selectedPort)
                    uialert(app.UIFigure, 'No serial ports available.', 'Connection Error');
                    return;
                end
                
                try
                    baudRate = str2double(app.BaudrateDropDown.Value);
                    app.SerialConnection = serialport(selectedPort, baudRate);
                    % app.SerialConnection = arduino(app.port,'mega');
                    app.StatusLamp.Color = [0, 1, 0];
                    app.ConnectButton.Text = 'Disconnect';
        
                    app.NameListBox.Enable = 'off';
                    app.BaudrateDropDown.Enable = 'off';
                    app.PhysicalModeCheckBox.Enable = 'off';
                    app.SetButton.Enable = 'on';
                    app.StartButton.Enable = 'on';
                catch ME
                    errorMessage = sprintf('Failed to connect to the selected port. Error: %s', ME.message);
                    uialert(app.UIFigure, errorMessage, 'Connection Error');
                    app.StatusLamp.Color = [1, 0, 0];
                end
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            setpoint = str2double(app.degEditField.Value);
            if isnan(setpoint)
                uialert(app.UIFigure, 'Invalid setpoint value. Please enter a numeric value.', 'Input Error');
                return;
            end
            
            Kp = str2double(app.KpEditField.Value);
            Ki = str2double(app.KiEditField.Value);
            Kd = str2double(app.KdEditField.Value);
            if isnan(Kp) || isnan(Ki) || isnan(Kd)
                uialert(app.UIFigure, 'Invalid Kp, Ki, or Kd value(s). Please enter numeric values.', 'Input Error');
                return;
            end
            
            assignin('base', 'setpoint', setpoint);
            disp(['Setpoint stored in workspace: ', num2str(setpoint)]);
            
            if isempty(app.SerialConnection) || ~isvalid(app.SerialConnection)
                uialert(app.UIFigure, 'Serial connection is not open.', 'Serial Port Error');
                return;
            end
            
            try
                write(app.SerialConnection, 0x03, "uint8");
                write(app.SerialConnection, setpoint, "single");
                write(app.SerialConnection, 0x04, "uint8");
                write(app.SerialConnection, Kp, "single");
                write(app.SerialConnection, 0x05, "uint8");
                write(app.SerialConnection, Ki, "single");
                write(app.SerialConnection, 0x06, "uint8");
                write(app.SerialConnection, Kd, "single");
            catch ME
                uialert(app.UIFigure, 'Failed to send data to the serial port.', 'Serial Port Error');
                disp(['Error sending data: ', ME.message]);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if strcmp(app.StartButton.Text, 'Start')
                evalin('base', 'voltage = 0;');
                evalin('base', 'feedback = 0;');

                app.index = 1;
                app.time = [];
                app.feedback = [];
                if ~bdIsLoaded('main') && app.PhysicalModeCheckBox.Value == 0
                    msgbox('Simulink model "main" is not open. Please open it before running the simulation.', 'Model Not Open', 'error');
                else
                    if app.PhysicalModeCheckBox.Value
                        cla(app.UIAxes);
                        axis(app.UIAxes, 'auto');

                        app.StartButton.Text = 'Stop';
        
                        SetButtonPushed(app, event);
        
                        app.KpEditField.Enable = 'off';
                        app.KiEditField.Enable = 'off';
                        app.KdEditField.Enable = 'off';

                        write(app.SerialConnection, 0x00, "uint8");
                        
                        tic;
                        start(app.timeCounterReal);

                    elseif strcmp(get_param('main', 'SimulationStatus'), 'stopped')
                        cla(app.UIAxes);
                        axis(app.UIAxes, 'auto');

                        app.StartButton.Enable = 'off';
                        app.SetButton.Enable = 'off';
                        app.StartButton.Text = 'Stop';
        
                        SetButtonPushed(app, event);
        
                        app.KpEditField.Enable = 'off';
                        app.KiEditField.Enable = 'off';
                        app.KdEditField.Enable = 'off';
                        
        
                        write(app.SerialConnection, 0x00, "uint8");
    
                        set_param('main', 'StopTime', 'Inf');

                        tic;
                        start(app.timeCounter);
                        sim('main');
                    else
                        msgbox('Simulation has not stopped yet!', 'Simulation Status', 'warning');
                    end

                end
            else
                if app.PhysicalModeCheckBox.Value == 0
                    stop(app.timeCounter);
                    set_param('main', 'SimulationCommand', 'stop');
                else
                    stop(app.timeCounterReal);
                end

                write(app.SerialConnection, 0x01, "uint8");
                configureCallback(app.SerialConnection, "off");

                % assignin('base', 'index', app.index);
                % assignin('base', 'time', app.time);
                app.StartButton.Text = 'Start';
                disp('Data capture stopped.');

                app.KpEditField.Enable = 'on';
                app.KiEditField.Enable = 'on';
                app.KdEditField.Enable = 'on';

                endPlot(app);
            end
        end

        % Value changed function: KpEditField
        function KpEditFieldValueChanged(app, event)
           value = str2double(app.KpEditField.Value);
            
            if evalin('base', 'exist(''K'', ''var'')') == 1
                K = evalin('base', 'K');
                K{1} = value;
                evalin('base', sprintf('K = {%f, %f, %f};', K{1}, K{2}, K{3}));
            end
        end

        % Value changed function: KiEditField
        function KiEditFieldValueChanged(app, event)
            value = str2double(app.KiEditField.Value);
            
            if evalin('base', 'exist(''K'', ''var'')') == 1
                K = evalin('base', 'K');
                K{2} = value;
                evalin('base', sprintf('K = {%f, %f, %f};', K{1}, K{2}, K{3}));
            end
        end

        % Value changed function: KdEditField
        function KdEditFieldValueChanged(app, event)
            value = str2double(app.KdEditField.Value);
            
            if evalin('base', 'exist(''K'', ''var'')') == 1
                K = evalin('base', 'K');
                K{3} = value;
                evalin('base', sprintf('K = {%f, %f, %f};', K{1}, K{2}, K{3}));
            end
        end

        % Value changed function: PhysicalModeCheckBox
        function PhysicalModeCheckBoxValueChanged(app, event)
            value = app.PhysicalModeCheckBox.Value;
            
            if value
                app.VEditField.Value = '0';
                app.VEditField.Enable = 'off';
            else
                app.degEditField.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Position vs. Time')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'position (degrees)')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SerialPortConnectionPanel = uipanel(app.GridLayout2);
            app.SerialPortConnectionPanel.Title = 'Serial Port Connection';
            app.GridLayout4 = uigridlayout(app.SerialPortConnectionPanel);
            app.Panel = uipanel(app.GridLayout4);
            app.NameListBoxLabel = uilabel(app.Panel);
            app.NameListBoxLabel.Text = 'Name';
            app.NameListBox = uilistbox(app.Panel);
            app.NameListBox.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.NameListBox.Value = 'COM1';
            app.BaudrateDropDownLabel = uilabel(app.Panel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.Panel);
            app.BaudrateDropDown.Items = {'4800', '9600', '19200', '38400', '57600', '115200', '230400', '460800', '921600'};
            app.BaudrateDropDown.Value = '9600';
            app.Panel_2 = uipanel(app.GridLayout4);
            app.PhysicalModeCheckBox = uicheckbox(app.Panel_2);
            app.PhysicalModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhysicalModeCheckBoxValueChanged, true);
            app.PhysicalModeCheckBox.Text = 'Physical Mode';
            app.ConnectButton = uibutton(app.Panel_2, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusLampLabel = uilabel(app.Panel_2);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.Panel_2);
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '----------';
            app.ControllerPanel = uipanel(app.GridLayout2);
            app.ControllerPanel.Title = 'Controller';
            app.GridLayout3 = uigridlayout(app.ControllerPanel);
            app.DesiredPositionPanel = uipanel(app.GridLayout3);
            app.DesiredPositionPanel.Title = 'Desired Position';
            app.degEditFieldLabel = uilabel(app.DesiredPositionPanel);
            app.degEditFieldLabel.Text = 'deg';
            app.degEditField = uieditfield(app.DesiredPositionPanel, 'text');
            app.degEditField.Value = '0';
            app.SetButton = uibutton(app.DesiredPositionPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Enable = 'off';
            app.SetButton.Text = 'Set';
            app.FeedbackPositionPanel = uipanel(app.GridLayout3);
            app.FeedbackPositionPanel.Title = 'Feedback Position';
            app.StartButton = uibutton(app.FeedbackPositionPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.degEditField_2Label = uilabel(app.FeedbackPositionPanel);
            app.degEditField_2Label.Text = 'deg';
            app.degEditField_2 = uieditfield(app.FeedbackPositionPanel, 'text');
            app.degEditField_2.Editable = 'off';
            app.degEditField_2.Value = '0';
            app.VEditFieldLabel = uilabel(app.FeedbackPositionPanel);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.FeedbackPositionPanel, 'text');
            app.VEditField.Editable = 'off';
            app.VEditField.Value = '0';
            app.Panel_3 = uipanel(app.GridLayout3);
            app.KpEditFieldLabel = uilabel(app.Panel_3);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.Panel_3, 'text');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @KpEditFieldValueChanged, true);
            app.KpEditField.Value = '1';
            app.KiEditFieldLabel = uilabel(app.Panel_3);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.Panel_3, 'text');
            app.KiEditField.ValueChangedFcn = createCallbackFcn(app, @KiEditFieldValueChanged, true);
            app.KiEditField.Value = '0';
            app.KdEditFieldLabel = uilabel(app.Panel_3);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.Panel_3, 'text');
            app.KdEditField.ValueChangedFcn = createCallbackFcn(app, @KdEditFieldValueChanged, true);
            app.KdEditField.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end